classdef DispersionAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InitialHeightmEditFieldLabel   matlab.ui.control.Label
        InitialHeightmEditField        matlab.ui.control.NumericEditField
        NumberoflunasatsperstackEditFieldLabel  matlab.ui.control.Label
        NumberoflunasatsperstackEditField  matlab.ui.control.NumericEditField
        LaunchSpringConstantEditFieldLabel  matlab.ui.control.Label
        LaunchSpringConstantEditField  matlab.ui.control.NumericEditField
        LaunchSpringDisplacementEditFieldLabel  matlab.ui.control.Label
        LaunchSpringDisplacementEditField  matlab.ui.control.NumericEditField
        LunasatMassEditFieldLabel      matlab.ui.control.Label
        LunasatMassEditField           matlab.ui.control.NumericEditField
        BayMassEditFieldLabel          matlab.ui.control.Label
        BayMassEditField               matlab.ui.control.NumericEditField
        LunasatPositionalRadiusEditFieldLabel  matlab.ui.control.Label
        LunasatPositionalRadiusEditField  matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        GravityEditFieldLabel          matlab.ui.control.Label
        GravityEditField               matlab.ui.control.NumericEditField
        EjectionSpringConstantEditFieldLabel  matlab.ui.control.Label
        EjectionSpringConstantEditField  matlab.ui.control.NumericEditField
        EjectionSpringDisplacementEditFieldLabel  matlab.ui.control.Label
        EjectionSpringDisplacementEditField  matlab.ui.control.NumericEditField
        NumberofStacksEditFieldLabel   matlab.ui.control.Label
        NumberofStacksEditField        matlab.ui.control.NumericEditField
        RotationRateRPSEditFieldLabel  matlab.ui.control.Label
        RotationRateRPSEditField       matlab.ui.control.NumericEditField
        LaunchAngleEditFieldLabel      matlab.ui.control.Label
        LaunchAngleEditField           matlab.ui.control.NumericEditField
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
       
            % Initial Launch Height
            h = app.InitialHeightmEditField.Value;
            
            % Environmental Const
            g = app.GravityEditField.Value;                     % Gravity (m/s^2)
            gVec = [0,0,-g];                                    % Gravity vector
            
            phi = app.LaunchAngleEditField.Value;
            theta = 0;
            
            % Spring Properties
            dx = app.EjectionSpringConstantEditField.Value;     % ejection spring compression length (m)
            k = app.EjectionSpringDisplacementEditField.Value;  % ejection spring constant length (N/m)
            
            DX = app.LaunchSpringDisplacementEditField.Value;
            K = app.LaunchSpringConstantEditField.Value;
            
            % Mass Properties
            % Constants
            satM = app.LunasatMassEditField.Value;               % mass of one lunasat
            bayM = app.BayMassEditField.Value;                 % mass of lunasat bay structure (kg)
                                                
            % Launch Configuration
            
            %theta = 45;                 % degrees
            
            nCols = app.NumberofStacksEditField.Value;                % Num. Stacks 
            nRows = app.NumberoflunasatsperstackEditField.Value;                % Num. Rows
            
            
            % Rotational velocity to linear velocity magnitude
            r = app.LunasatPositionalRadiusEditField.Value;                % Radial distance use for rotational velocity linearization
            RPS = app.RotationRateRPSEditField.Value;           % Spin rate (Revolutions per sec)
            
            syms out1(t) out2(t) 
           
 
            [BayTraj(t), LunaTraj(t), Bimpact, out4, lunaImpactTs, lunaImpactPos] = DispersionAnalysisFun(g,h,satM,bayM,phi,theta,K,DX,k,dx,nCols,nRows,r,RPS);
            
            
            syms bplotX(t) bplotY(t) lplotX(t) lplotY(t) bayTrajPlot(t) lunaTrajPlot
            
            bayTrajPlot = BayTraj(t);
            lunaTrajPlot = LunaTraj(t);
            
            
            bplotX = bayTrajPlot(1);
            bplotY = bayTrajPlot(3);


            hold(app.UIAxes_2,'on');      
            hold(app.UIAxes_3,'on');
            hold(app.UIAxes,'on');
            
            fplot(app.UIAxes_2,bplotX,bplotY,[0,10],'r');
            
            for i = 1:4
                lplotX = lunaTrajPlot(i,1);
                lplotY = lunaTrajPlot(i,2);
                lplotZ = lunaTrajPlot(i,3);

                %fplot(app.UIAxes_2,lplotX,lplotZ,[0,10]);
                
                fplot(app.UIAxes_3,lplotY,lplotX,[0,10]);
                
            end


            scatter(app.UIAxes2,out4(:,1),out4(:,2));

            %scatter(app.UIAxes,Bimpact(2),Bimpact(1));
            disp(size(lunaImpactPos))
            for i = 1:500
                scatter(app.UIAxes,lunaImpactPos(i,2),lunaImpactPos(i,1),5,"red",'filled');
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitialHeightmEditFieldLabel = uilabel(app.UIFigure);
            app.InitialHeightmEditFieldLabel.Text = 'Initial Height (m)';
            app.InitialHeightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialHeightmEditField.Value = 1;
            app.NumberoflunasatsperstackEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflunasatsperstackEditFieldLabel.Text = 'Number of lunasats per stack';
            app.NumberoflunasatsperstackEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflunasatsperstackEditField.Value = 125;
            app.LaunchSpringConstantEditFieldLabel = uilabel(app.UIFigure);
            app.LaunchSpringConstantEditFieldLabel.Text = 'Launch Spring Constant';
            app.LaunchSpringConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaunchSpringConstantEditField.Value = 2228;
            app.LaunchSpringDisplacementEditFieldLabel = uilabel(app.UIFigure);
            app.LaunchSpringDisplacementEditFieldLabel.Text = 'Launch Spring Displacement';
            app.LaunchSpringDisplacementEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaunchSpringDisplacementEditField.Value = 0.1886;
            app.LunasatMassEditFieldLabel = uilabel(app.UIFigure);
            app.LunasatMassEditFieldLabel.Text = 'Lunasat Mass';
            app.LunasatMassEditField = uieditfield(app.UIFigure, 'numeric');
            app.LunasatMassEditField.Value = 0.005;
            app.BayMassEditFieldLabel = uilabel(app.UIFigure);
            app.BayMassEditFieldLabel.Text = 'Bay Mass';
            app.BayMassEditField = uieditfield(app.UIFigure, 'numeric');
            app.BayMassEditField.Value = 2.5;
            app.LunasatPositionalRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.LunasatPositionalRadiusEditFieldLabel.Text = 'Lunasat Positional Radius';
            app.LunasatPositionalRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.LunasatPositionalRadiusEditField.Value = 0.04;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GravityEditFieldLabel = uilabel(app.UIFigure);
            app.GravityEditFieldLabel.Text = 'Gravity';
            app.GravityEditField = uieditfield(app.UIFigure, 'numeric');
            app.GravityEditField.Value = 1.62;
            app.EjectionSpringConstantEditFieldLabel = uilabel(app.UIFigure);
            app.EjectionSpringConstantEditFieldLabel.Text = 'Ejection Spring Constant';
            app.EjectionSpringConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.EjectionSpringDisplacementEditFieldLabel = uilabel(app.UIFigure);
            app.EjectionSpringDisplacementEditFieldLabel.Text = 'Ejection Spring Displacement';
            app.EjectionSpringDisplacementEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofStacksEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStacksEditFieldLabel.Text = 'Number of Stacks';
            app.NumberofStacksEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofStacksEditField.Value = 4;
            app.RotationRateRPSEditFieldLabel = uilabel(app.UIFigure);
            app.RotationRateRPSEditFieldLabel.Text = 'Rotation Rate (RPS)';
            app.RotationRateRPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationRateRPSEditField.Value = 5;
            app.LaunchAngleEditFieldLabel = uilabel(app.UIFigure);
            app.LaunchAngleEditFieldLabel.Text = 'Launch Angle';
            app.LaunchAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaunchAngleEditField.Value = -45;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Bay Trajectory')
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Z (m)')
            app.UIAxes_2.YLim = [0 12];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dispersion')
            xlabel(app.UIAxes, 'Y')
            ylabel(app.UIAxes, 'X')
            app.UIAxes.XLim = [-6 6];
            app.UIAxes.YLim = [0 60];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Ejection Port Locations')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [-1 1];
            app.UIAxes2.YLim = [-1 1];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Trajectory XY')
            xlabel(app.UIAxes_3, 'X (m)')
            ylabel(app.UIAxes_3, 'Z (m)')
            app.UIAxes_3.XLim = [-20 20];
            app.UIAxes_3.YLim = [0 12];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DispersionAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end